classdef FKin_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EndEffectorPositionEditField  matlab.ui.control.NumericEditField
        EndEffectorPositionEditFieldLabel  matlab.ui.control.Label
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        robot = []; % This is a Serial Link Created robot 
        jointangles= [ 0 0]; % these are the joint angles of the robot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function theta1(app)
            
        end

        % Value changed function: Joint1Slider
        function joint1(app, event)
            theta1 = app.Joint1Slider.Value;
            %calculate the forward kinematics
            T=app.robot.fkine(theta1); %for 1dof (need to change)
            robotPosition = T.t; %get the position data
            app.endEffectorPos.Value = robotPosition(1); %update the UI
        end

        % Value changed function: Joint2Slider
        function joint2(app, event)
            theta2 = app.Joint1Slider.Value;
            %calculate the forward kinematics
            T=app.robot.fkine(theta2); %for 1dof (need to change)
            robotPosition = T.t; %get the position data
            app.endEffectorPos.Value = robotPosition(2);
            
        end

        % Value changed function: EndEffectorPositionEditField
        function endEffectorPos(app, event)
            Value = app.EndEffectorPositionEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @joint1, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @joint2, true);
            app.EndEffectorPositionEditFieldLabel = uilabel(app.UIFigure);
            app.EndEffectorPositionEditFieldLabel.Text = 'End Effector Position';
            app.EndEffectorPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndEffectorPositionEditField.ValueChangedFcn = createCallbackFcn(app, @endEffectorPos, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FKin_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @theta1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end